classdef MATLAB_UART < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RS232ACTIVOLamp            matlab.ui.control.Lamp
        RS232ACTIVOLampLabel       matlab.ui.control.Label
        GRAFICARButton             matlab.ui.control.Button
        AsigneelPuertoSerieCorrectoLabel  matlab.ui.control.Label
        DESCONECTARButton          matlab.ui.control.Button
        CONECTARButton             matlab.ui.control.Button
        GRAFICANDOLamp             matlab.ui.control.Lamp
        GRAFICANDOLampLabel        matlab.ui.control.Label
        PUERTOSERIEEditField       matlab.ui.control.EditField
        PUERTOSERIEEditFieldLabel  matlab.ui.control.Label
        MenIJosAntoniodeJessArredondoGarzaLabel  matlab.ui.control.Label
        UNIVERSIDADNACIONALAUTNOMADEMXICOFACULTADDEINGENIERALabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        GRAFICACINENTIEMPOYFRECUENCIAMATLABRASPBERRYPIPICOLabel  matlab.ui.control.Label
        SALIRButton                matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % XOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXO %
        % XOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXO %
        %                                                                %
        %            UNIVERSIDAD NACIONAL AUTÓNOMA DE MÉXICO             %
        %                     FACULTAD DE INGENIERÍA                     %
        %               DEPARTAMENTO DE CONTROL Y ROBÓTICA               %
        %          M. EN I. JOSÉ ANTONIO DE JESÚS ARREDONDO GARZA        %
        %                      EMAIL: jarredon@unam.mx                   %
        %                              AÑO 2023                          %
        %                                                                %
        % XOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXO %
        % XOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXOXO %


        % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% % 
        %                                                           %
        %    DECLARACIÓN DE VARIABLES Y DEL OBJETO QUE CONTENDRÁ    %
        %    AL PUERTO SERIE QUE SE USARÁ.                          % 
        %                                                           %
        %    NOTA: cuando se está creando este proyecto se crea     %
        %          con el botón de "Propiedades - private".         %
        %                                                           %
        % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %

        Fs;                       % Frecuencia de Muestreo.
        Ts;                       % Tiempo de Muestreo.
        t;                        % Eje del tiempo para graficación.
        f;                        % Eje de la frecuencia para graficación.
        PUERTO;                   % Creación del objeto serial.
        Size_nFFT;                % Número de Muestras para graficación.
%        CONNECT;                  % Bandera para indicar conexión.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %
            %                                                 %
            %      RUTINA DE INICIALIZACIÓN DEL PROGRAMA      %
            %      NOTA: esta función se crea pulsando el     %
            %            botón "Function + startupFcn"        %
            %                                                 %
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %

            app.GRAFICANDOLamp.Color  = "Red"; % Pon en rojo el LED.
            app.RS232ACTIVOLamp.Color = "Red"; % Led RS232.
            clear;                             % Limpia todos los objetos.
            warndlg(['¡ Asegúrese que el programa en la Raspberry Pi' ...
                ' Pico se esté ejecutando antes de pulsar el ' ...
                'botón CONECTAR !'], 'MENSAJE')
%            app.CONNECT = 0;                   % Bandera de CONEXIÓN = 0.

        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %
            %                                                %
            %      RUTINA PARA CERRAR TODA LA APLICACIÓN     %
            %                                                %
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %

            close(app.UIFigure);
            clear;
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%&& %
            %                                                            &
            %   FUNCIÓN QUE ASIGNA FRECUENCIA DE MUESTREO, TIEMPO DE     %
            %   MUESTREO, CÁLCULO DE LOS EJES DEL TIEMPO Y FRECUENCIA,   %
            %   Y REALIZAR LA CONEXIÓN CON EL PUERTO SERIE.              %
            %                                                            &
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %

            app.GRAFICANDOLamp.Color = "Red";     % Cambia de color al LED.
            app.Fs        = 8000;                 % Frecuencia de Muestreo.
            app.Ts        = (1/app.Fs);           % Tiempo de Muestreo.
            app.Size_nFFT = 500;                  % Número de Muestras.

            % xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx %
            %   Creación de los ejes de tiempo y frecuencia   %
            % xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx %

            % Eje del Tiempo para Gráficación.
            app.t = (0:app.Size_nFFT-1)*app.Ts;
            % Eje de la Frecuencia para Graficación.
            app.f = (0:app.Size_nFFT/2-1)*(app.Fs/app.Size_nFFT);

            % xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx %
            %   Realización de la Conexión con el Puerto Serie "UART"   %
            % xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx %

            Texto = app.PUERTOSERIEEditField.Value;  % Lee que puerto está
                                                     % escrito en el
                                                     % cuadro de texto.

            %**********************%
            % Crea el Puerto Serie %
            %**********************%

            app.PUERTO  = serialport(Texto,460800);
            app.PUERTO.InputBufferSize = app.Size_nFFT*8;      % Bufer de 4000 bytes.

            %**********************%
            % Abre el Puerto Serie %
            %**********************%

            fopen(app.PUERTO);
            app.RS232ACTIVOLamp.Color = "Green";

%            app.CONNECT = 1;         

             
        end

        % Button pushed function: DESCONECTARButton
        function DESCONECTARButtonPushed(app, event)

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %
            %                                              %
            %      RUTINA PARA CERRAR EL PUERTO SERIE      %
            %                                              %
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %


            fclose(app.PUERTO);                   % Cerrar PUERTO.
            app.GRAFICANDOLamp.Color  = "Red";    % LED en color rojo.
            app.RS232ACTIVOLamp.Color = "Red";    % LED RS232 en Rojo.


        end

        % Button pushed function: GRAFICARButton
        function GRAFICARButtonPushed(app, event)

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %
            %                                                           %
            %   RUTINA QUE SE ENCARGA DE RECIBIR Y GRAFICAR LOS DATOS   %
            %   LOS DATOS PROVENIENTES DEL PUERTO SERIE.                %
            %                                                           %
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %

                
            % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %
            %                                                        %
            %   REPITE EL CICLO DE ADQUISICIÓN Y GRAFICACIÓN 30 SEG  %
            %                                                        %
            % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %

            for j = 1:30      %   OJO   OJO   OJO   OJO   OJO

                %************************************&
                % Datos de Entrada y Salida en ceros %
                %************************************%

                IN  = zeros(1, app.Size_nFFT);      % Son 500 datos de las entradas,
                OUT = zeros(1, app.Size_nFFT);      % SOn 500 datos de las salidas.

                %*************************************%
                %  Indicador de Graficación en Verde  %
                %*************************************%

                app.GRAFICANDOLamp.Color = "Green";  

                %*****************************************%
                %  Escribe el caracter guía "U" a la RP2  %
                %*****************************************%

                fwrite(app.PUERTO, 85, "uint8");
                
                %***************************************%
                % Lee el Puerto Serie "COMx", que son:  %
                %       500 muestras para INPUT         %
                %       500 muestras para OUTPUT        %
                % NOTA: como los datos se reciben en    %
                %       formato ENDIAN, aquí MATLAB     %
                %       los reconvierte a un formato    %
                %       "single" (float).               %
                % NOTA: los datos de entrada y salida   %
                %       están intercalados para evitar  %
                %       que aparezca un corrimiento en  %
                %       frecuencia indesable.           %
                %***************************************%

                READ = fread(app.PUERTO, app.Size_nFFT * 2, "single");

                %******************************************%
                %   Separación de las Entradas y Salidas   %
                %******************************************%

                r = 1;
                for i = 1:app.Size_nFFT
                    IN(i)  = READ(r);
                    OUT(i) = READ(r + 1);
                    r = r + 2;
                end

                %**************************************************%
                %    Obten las Transformadas Rápidas de Fourier    %
                %**************************************************%

                A_IN   = fft(IN,  app.Size_nFFT);     % FFT de INPUT.
                A_IN   = A_IN(1:app.Size_nFFT/2);     % Decimación.
                A_INx  = abs(A_IN);                   % Absoluto.

                B_OUT  = fft(OUT, app.Size_nFFT);     % FFT de OUTPUT.
                B_OUT  = B_OUT(1:app.Size_nFFT/2);    % Decimación.
                B_OUTx = abs(B_OUT);                  % Absoluto.
                
                %***************************************************%
                %   Proceso de Graficación en Tiempo y Frecuencia   %
                %***************************************************%

                plot(app.UIAxes,   app.t, IN, 'g', app.t, OUT, 'r')
                drawnow

                plot(app.UIAxes_2, app.f, A_INx,'g',app.f, B_OUTx,'r')
                drawnow
            end

            app.GRAFICANDOLamp.Color = "Red";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graficas en el Dominio del Tiempo')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Graficas en el Dominio de la Frecuencia')
            xlabel(app.UIAxes_2, 'Frecuencia')
            ylabel(app.UIAxes_2, 'Magnitud')
            zlabel(app.UIAxes_2, 'Z')
            app.SALIRButton = uibutton(app.UIFigure, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            app.GRAFICACINENTIEMPOYFRECUENCIAMATLABRASPBERRYPIPICOLabel = uilabel(app.UIFigure);
            app.GRAFICACINENTIEMPOYFRECUENCIAMATLABRASPBERRYPIPICOLabel.Text = 'GRAFICACIÓN EN TIEMPO Y FRECUENCIA MATLAB - RASPBERRY PI PICO';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UNAM.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Ingenieria.jpg');
            app.UNIVERSIDADNACIONALAUTNOMADEMXICOFACULTADDEINGENIERALabel = uilabel(app.UIFigure);
            app.UNIVERSIDADNACIONALAUTNOMADEMXICOFACULTADDEINGENIERALabel.Text = 'UNIVERSIDAD NACIONAL AUTÓNOMA DE MÉXICO - FACULTAD DE INGENIERÍA';
            app.MenIJosAntoniodeJessArredondoGarzaLabel = uilabel(app.UIFigure);
            app.MenIJosAntoniodeJessArredondoGarzaLabel.Text = 'M. en I. José Antonio de Jesús Arredondo Garza';
            app.PUERTOSERIEEditFieldLabel = uilabel(app.UIFigure);
            app.PUERTOSERIEEditFieldLabel.Text = 'PUERTO SERIE';
            app.PUERTOSERIEEditField = uieditfield(app.UIFigure, 'text');
            app.PUERTOSERIEEditField.Value = 'COM13';
            app.GRAFICANDOLampLabel = uilabel(app.UIFigure);
            app.GRAFICANDOLampLabel.Text = 'GRAFICANDO';
            app.GRAFICANDOLamp = uilamp(app.UIFigure);
            app.CONECTARButton = uibutton(app.UIFigure, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Text = 'CONECTAR';
            app.DESCONECTARButton = uibutton(app.UIFigure, 'push');
            app.DESCONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @DESCONECTARButtonPushed, true);
            app.DESCONECTARButton.Text = 'DESCONECTAR';
            app.AsigneelPuertoSerieCorrectoLabel = uilabel(app.UIFigure);
            app.AsigneelPuertoSerieCorrectoLabel.Text = 'Asigne el Puerto Serie Correcto:';
            app.GRAFICARButton = uibutton(app.UIFigure, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.RS232ACTIVOLampLabel = uilabel(app.UIFigure);
            app.RS232ACTIVOLampLabel.Text = 'RS232 ACTIVO';
            app.RS232ACTIVOLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_UART

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end